classdef app_CALCULATOR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TANButton             matlab.ui.control.Button
        COSINEButton          matlab.ui.control.Button
        SINEButton            matlab.ui.control.Button
        CALCULATORLabel       matlab.ui.control.Label
        powerButton           matlab.ui.control.Button
        divisionButton        matlab.ui.control.Button
        multiplicationButton  matlab.ui.control.Button
        subtractionButton     matlab.ui.control.Button
        additionButton        matlab.ui.control.Button
        ansEditField          matlab.ui.control.NumericEditField
        ansEditFieldLabel     matlab.ui.control.Label
        num2EditField         matlab.ui.control.NumericEditField
        num2EditFieldLabel    matlab.ui.control.Label
        num1EditField         matlab.ui.control.NumericEditField
        num1EditFieldLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: additionButton
        function additionButtonPushed(app, event)
            a=app.num1EditField.Value;
            b=app.num2EditField.Value;
            c=a+b;
            app.ansEditField.Value=c;
        end

        % Button pushed function: subtractionButton
        function subtractionButtonPushed(app, event)
            a=app.num1EditField.Value;
            b=app.num2EditField.Value;
            s=a-b;
            app.ansEditField.Value=s;   
        end

        % Button pushed function: multiplicationButton
        function multiplicationButtonPushed(app, event)
            a=app.num1EditField.Value;
            b=app.num2EditField.Value;
            m=a*b;
            app.ansEditField.Value=m;  
        end

        % Button pushed function: divisionButton
        function divisionButtonPushed(app, event)
            a=app.num1EditField.Value;
            b=app.num2EditField.Value;
            d=a/b;
            app.ansEditField.Value=d;
        end

        % Button pushed function: powerButton
        function powerButtonPushed(app, event)
            a=app.num1EditField.Value;
            b=app.num2EditField.Value;
            p=a^b;
            app.ansEditField.Value=p;
        end

        % Button pushed function: SINEButton
        function SINEButtonPushed(app, event)
            a=app.num1EditField.Value;
            Sine=sind(a);
            app.ansEditField.Value=Sine;
        end

        % Button pushed function: COSINEButton
        function COSINEButtonPushed(app, event)
            a=app.num1EditField.Value;
            Cos=cosd(a);
            app.ansEditField.Value=Cos;
        end

        % Button pushed function: TANButton
        function TANButtonPushed(app, event)
            a=app.num1EditField.Value;
            Tan=tand(a);
            app.ansEditField.Value=Tan;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.num1EditFieldLabel = uilabel(app.UIFigure);
            app.num1EditFieldLabel.Text = 'num 1';
            app.num1EditField = uieditfield(app.UIFigure, 'numeric');
            app.num2EditFieldLabel = uilabel(app.UIFigure);
            app.num2EditFieldLabel.Text = 'num 2';
            app.num2EditField = uieditfield(app.UIFigure, 'numeric');
            app.ansEditFieldLabel = uilabel(app.UIFigure);
            app.ansEditFieldLabel.Text = 'ans';
            app.ansEditField = uieditfield(app.UIFigure, 'numeric');
            app.additionButton = uibutton(app.UIFigure, 'push');
            app.additionButton.ButtonPushedFcn = createCallbackFcn(app, @additionButtonPushed, true);
            app.additionButton.Text = ' addition';
            app.subtractionButton = uibutton(app.UIFigure, 'push');
            app.subtractionButton.ButtonPushedFcn = createCallbackFcn(app, @subtractionButtonPushed, true);
            app.subtractionButton.Text = 'subtraction';
            app.multiplicationButton = uibutton(app.UIFigure, 'push');
            app.multiplicationButton.ButtonPushedFcn = createCallbackFcn(app, @multiplicationButtonPushed, true);
            app.multiplicationButton.Text = 'multiplication';
            app.divisionButton = uibutton(app.UIFigure, 'push');
            app.divisionButton.ButtonPushedFcn = createCallbackFcn(app, @divisionButtonPushed, true);
            app.divisionButton.Text = 'division';
            app.powerButton = uibutton(app.UIFigure, 'push');
            app.powerButton.ButtonPushedFcn = createCallbackFcn(app, @powerButtonPushed, true);
            app.powerButton.Text = 'power';
            app.CALCULATORLabel = uilabel(app.UIFigure);
            app.CALCULATORLabel.Text = 'CALCULATOR';
            app.SINEButton = uibutton(app.UIFigure, 'push');
            app.SINEButton.ButtonPushedFcn = createCallbackFcn(app, @SINEButtonPushed, true);
            app.SINEButton.Text = 'SINE';
            app.COSINEButton = uibutton(app.UIFigure, 'push');
            app.COSINEButton.ButtonPushedFcn = createCallbackFcn(app, @COSINEButtonPushed, true);
            app.COSINEButton.Text = 'COSINE';
            app.TANButton = uibutton(app.UIFigure, 'push');
            app.TANButton.ButtonPushedFcn = createCallbackFcn(app, @TANButtonPushed, true);
            app.TANButton.Text = 'TAN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_CALCULATOR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end